classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        DefinizionedeipuntidicontrolloLabel  matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        ResetButton                   matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        InterpolazioneButton          matlab.ui.control.Button
        SelezionaButton               matlab.ui.control.Button
        CaricaButton                  matlab.ui.control.Button
        SelezionaipuntidicontrolloLabel  matlab.ui.control.Label
        ModellidiinterpolazioneLabel  matlab.ui.control.Label
        CaricaunimmagineLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        SEGMENTAZIONE2DLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        xCoords;
        yCoords;
        image;
        WinInterpolation;
    end
    
    methods (Access = private)
        
        function resetAxisImage(app)
            cla(app.UIAxes);
            axis(app.UIAxes, 'equal');
            imshow(app.image, 'Parent', app.UIAxes);
            app.UIAxes.Visible = 'on';
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.xCoords = [];
            app.yCoords = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Visible = false;

            app.SelezionaButton.Enable = false;
            app.InterpolazioneButton.Enable = false;

        end

        % Button pushed function: CaricaButton
        function CaricaButtonPushed(app, event)
            %Crea finestra di dialogo per selezionare l'immagine
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.jpeg', ...
                'File immagine (*.jpg, *.png, *.bmp *.jpeg)'});
            % Verifica se un'immagine è stata selezionata correttamente
            if isequal(filename, 0)
                % Nessuna immagine selezionata o selezione annullata
                return;
            else
                % Percorso completo dell'immagine selezionata
                app.SelezionaButton.Enable = true;
                app.InterpolazioneButton.Enable = false;
                app.xCoords = [];
                app.yCoords = [];
                imageFullPath = fullfile(pathname, filename);
                app.image = imread(imageFullPath);
                imshow(app.image, 'Parent', app.UIAxes);
                app.UIAxes.Visible = 'on';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
            end
        end

        % Button pushed function: SelezionaButton
        function SelezionaButtonPushed(app, event)
            app.CaricaButton.Enable = false;
            app.ResetButton.Enable = false;
            app.SelezionaButton.Enable = false;
            app.TextArea.Visible = true;
            app.InterpolazioneButton.Enable = false;

            app.resetAxisImage;

            hold(app.UIAxes, 'on');
            [app.xCoords, app.yCoords] = getpts(app.UIAxes);
            hold(app.UIAxes, 'off');

            xlims = xlim(app.UIAxes);
            ylims = ylim(app.UIAxes);

            outOfBoundsIndices = (app.xCoords < xlims(1) | app.xCoords > xlims(2)) | (app.yCoords < ylims(1) | app.yCoords > ylims(2));
            if any(outOfBoundsIndices)
                % Ci sono punti al di fuori degli assi
                %Reset degli assi e reinserimento dell'immagine
                app.resetAxisImage;
                app.InterpolazioneButton.Enable = false;
                msgbox('Hai inserito dei punti che si trovano al di fuori degli assi, riseleziona i punti', 'Errore inserimento punti di controllo', 'error')
            else
                app.InterpolazioneButton.Enable = true;
            end

            app.CaricaButton.Enable = true;
            app.ResetButton.Enable = true;
            app.SelezionaButton.Enable = true;
            app.TextArea.Visible = false;

            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.xCoords, app.yCoords, 'o', 'MarkerSize', ...
                10, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'black')
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.xCoords = [];
            app.yCoords = [];
            cla(app.UIAxes);
            axis(app.UIAxes, 'equal');
            app.SelezionaButton.Enable = false;
            app.InterpolazioneButton.Enable = false;
        end

        % Button pushed function: InterpolazioneButton
        function InterpolazioneButtonPushed(app, event)
            app.WinInterpolation = app2(app.xCoords, app.yCoords, app.image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SEGMENTAZIONE2DLabel = uilabel(app.GridLayout);
            app.SEGMENTAZIONE2DLabel.Text = 'SEGMENTAZIONE 2D';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Punti di controllo')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'AxisPoints';
            app.CaricaunimmagineLabel = uilabel(app.GridLayout2);
            app.CaricaunimmagineLabel.Text = 'Carica un''immagine:';
            app.ModellidiinterpolazioneLabel = uilabel(app.GridLayout2);
            app.ModellidiinterpolazioneLabel.Text = 'Modelli di interpolazione:';
            app.SelezionaipuntidicontrolloLabel = uilabel(app.GridLayout2);
            app.SelezionaipuntidicontrolloLabel.Text = 'Seleziona i punti di controllo:';
            app.CaricaButton = uibutton(app.GridLayout2, 'push');
            app.CaricaButton.ButtonPushedFcn = createCallbackFcn(app, @CaricaButtonPushed, true);
            app.CaricaButton.Text = 'Carica';
            app.SelezionaButton = uibutton(app.GridLayout2, 'push');
            app.SelezionaButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionaButtonPushed, true);
            app.SelezionaButton.Text = 'Seleziona';
            app.InterpolazioneButton = uibutton(app.GridLayout2, 'push');
            app.InterpolazioneButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolazioneButtonPushed, true);
            app.InterpolazioneButton.Text = 'Interpolazione';
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Premi ''Invio'' per terminare la selezione dei punti di controllo.'};
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DefinizionedeipuntidicontrolloLabel = uilabel(app.GridLayout);
            app.DefinizionedeipuntidicontrolloLabel.Text = 'Definizione dei punti di controllo:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = ' Questo software permette di risolvere il problema di interpolazione a partire da dei punti di controllo tracciati su un''immagine a due dimensioni.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end