classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DescrizioneLabel                matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ChiudiXButton_5                 matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        Panel_7                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ChiudiXButton_4                 matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        ScegliilmodellodiinterpolazioneLabel  matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        ChiudiXButton_3                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ChiudiXButton_2                 matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        BezierCurve_YLabel              matlab.ui.control.Label
        BezierCurve_XLabel              matlab.ui.control.Label
        EquazionidellaBezierCurveLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        MatricediBernsteinLabel_2       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ChiudiXButton                   matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        EditField_14                    matlab.ui.control.NumericEditField
        GridLayout13                    matlab.ui.container.GridLayout
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.EditField
        VettoredeipesiLabel             matlab.ui.control.Label
        VisualizzailplotdellefunzioniButton_2  matlab.ui.control.Button
        PlotdellefunzionidibaseLabel    matlab.ui.control.Label
        FissaipuntiCheckBox_2           matlab.ui.control.CheckBox
        EditField_15                    matlab.ui.control.EditField
        VettoredeinoditLabel_2          matlab.ui.control.Label
        GradoLabel_2                    matlab.ui.control.Label
        EditField_13                    matlab.ui.control.EditField
        CoordinatepuntidicontrolloLabel_3  matlab.ui.control.Label
        NumerodeipuntidicontrolloLabel_4  matlab.ui.control.Label
        ConfigurazioneLabel_4           matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        GradoLabel                      matlab.ui.control.Label
        PlotdellefunzionidibaseLabel_2  matlab.ui.control.Label
        VettoredeinoditLabel            matlab.ui.control.Label
        CoordinatepuntidicontrolloLabel_2  matlab.ui.control.Label
        NumerodeipuntidicontrolloLabel_3  matlab.ui.control.Label
        ConfigurazioneLabel_3           matlab.ui.control.Label
        VisualizzailplotdellefunzioniButton  matlab.ui.control.Button
        FissaipuntiCheckBox             matlab.ui.control.CheckBox
        EditField_10                    matlab.ui.control.EditField
        GridLayout6_4                   matlab.ui.container.GridLayout
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.EditField
        GridLayout6_3                   matlab.ui.container.GridLayout
        EditField_9                     matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        PlotdeipolinomidiBernsteinLabel  matlab.ui.control.Label
        CurvadiBezierLabel              matlab.ui.control.Label
        MatricediBernsteinLabel         matlab.ui.control.Label
        CoordinatepuntidicontrolloLabel  matlab.ui.control.Label
        NumerodeipuntidicontrolloLabel_2  matlab.ui.control.Label
        ConfigurazioneLabel_2           matlab.ui.control.Label
        VisualizzaleequazionidellacurvaButton  matlab.ui.control.Button
        VisualizzailplotdeipolinomidiBernsteinButton  matlab.ui.control.Button
        VisualizzalamatricediBernsteinButton  matlab.ui.control.Button
        EditField_5                     matlab.ui.control.EditField
        GridLayout6_2                   matlab.ui.container.GridLayout
        EditField_7                     matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        EditField_4                     matlab.ui.control.EditField
        VettoretdeiquerypointsLabel     matlab.ui.control.Label
        InserisciilpassoperilvettoretdeiquerypointsLabel  matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.EditField
        GridLayout6                     matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.NumericEditField
        VettoresdegliindiciLabel        matlab.ui.control.Label
        NumerodeipuntidicontrolloLabel  matlab.ui.control.Label
        ConfigurazioneLabel             matlab.ui.control.Label
        MostraimmagineCheckBox          matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        MODELLIDIINTERPOLAZIONELabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        image;
        xCoords;
        yCoords;
        methodSelected;

        %bpline variables
        xBSpline;
        yBSpline;
        gBSpline;
        tBSpline;

        %nurbs variables
        xNurbs;
        yNurbs;
        gNurbs;
        tNurbs;
        wNurbs;
    end
    
    methods (Access = private)
        
        function setConfigPanelLineareATratti(app)
            %Rendo visibile le componenti per la configurazione
            app.Panel.Visible = true;
            %Nascondo tutti gli altri pannelli
            app.Panel_2.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_6.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_8.Visible = false;
            app.Panel_9.Visible = false;
            %Imposto i dati nel pannello di configurazione
            app.EditField.Value = length(app.xCoords);
            s = 1 : length(app.xCoords);
            app.EditField_2.Value = num2str(s);
            t = 1 : app.EditField_3.Value : length(app.xCoords);
            app.EditField_4.Value = sprintf('%.2f ', t);

        end
        
        function [p1, p2] = interpolazioneATratti(~, s, t, x, y)
            p1 = interp1 (s, x, t);
            p2 = interp1 (s, y, t);
        end
        
        function setImageAndPoints(app, x, y)
            cla(app.UIAxes);
            if app.MostraimmagineCheckBox.Value == 1
                imshow(app.image, 'Parent', app.UIAxes);
                app.UIAxes.Visible = 'on';
                app.UIAxes.XTickMode = 'auto';
                app.UIAxes.YTickMode = 'auto';
            end

            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'o', 'MarkerSize', ...
                4, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r')
            hold(app.UIAxes, 'off');
        end
        
        function setConfigPanelBezier(app)
            %Rendo visibile le componenti per la configurazione
            app.Panel_2.Visible = true;
            %Nascondo gli altri pannelli
            app.Panel.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_6.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_8.Visible = false;
            app.Panel_9.Visible = false;
            %Imposto i dati nel pannello di configurazione
            app.EditField_7.Value = length(app.xCoords);
            P = strings(length(app.xCoords), 1);
            for i = 1:length(app.xCoords)
                P(i) = strcat(' P', num2str(i), ' [', num2str(app.xCoords(i)), '; ', num2str(app.yCoords(i)), '] ');
            end
            app.EditField_5.Value = join(P, newline);
            
        end
        
        function setConfigPanelBSpline(app)
            %Rendo visibile le componenti per la configurazione
            app.Panel_6.Visible = true;
            %Nascondo gli altri pannelli
            app.Panel.Visible = false;
            app.Panel_2.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_8.Visible = false;
            app.Panel_9.Visible = false;
            app.xBSpline = app.xCoords;
            app.yBSpline = app.yCoords;
            app.gBSpline = 3;
            app.EditField_9.Value = length(app.xBSpline);
            P = strings(length(app.xBSpline), 1);
            for i = 1:length(app.xBSpline)
                P(i) = strcat(' [', num2str(app.xBSpline(i)), ';', num2str(app.yBSpline(i)), ']');
            end
            stringP = join(P, newline);
            app.EditField_8.Value = stringP;
            app.EditField_11.Value = app.gBSpline;
            grado = app.gBSpline;
            t = [zeros(grado + 1, 1)' sort(rand(size(app.xCoords, 1) - grado - 1, 1))' ...
                ones(grado + 1, 1)'];
            app.tBSpline = t;
            app.EditField_10.Value = num2str(t);

        end
        
        function [C] = bspline_deboor_fun(app, n, t, P)
            validateattributes(n, {'numeric'}, {'positive','integer','scalar'});
            d = n-1;
            validateattributes(t, {'numeric'}, {'real','vector'});
            assert(all(t(2:end) >= t(1:end-1)), ...
                'bspline:deboor:InvalidArgumentValue', ...
                'Il vettore dei nodi deve essere in ordine crescente.');
            validateattributes(P, {'numeric'}, {'real','2d'});
            nctrl = numel(t)-(d+1);
            assert(size(P,2) == nctrl, 'bspline:deboor:DimensionMismatch', ...
                ['Numero dei punti di controllo scorretti, %d dati, %d ' ...
                'richiesti.'], size(P,2), nctrl);
            U = linspace(t(d+1), t(end-d), 10*size(P,2));
            m = size(P,1);                                                          
            t = t(:).';                                                            
            U = U(:);
            S = sum(bsxfun(@eq, U, t), 2);                                         
            I = app.bspline_deboor_interval(U,t);
            Pk = zeros(m,d+1,d+1);
            a = zeros(d+1,d+1);
            C = zeros(size(P,1), numel(U));
            for j = 1:numel(U)
                u = U(j);
                s = S(j);
                ix = I(j);
                Pk(:) = 0;
                a(:) = 0;
                Pk(:, (ix-d):(ix-s), 1) = P(:, (ix-d):(ix-s));
                h = d - s;                                                          
                if h > 0
                    for r = 1:h
                        q = ix-1;
                        for i = (q-d+r):(q-s)
                            a(i+1,r+1) = (u-t(i+1)) / (t(i+d-r+1+1)-t(i+1));
                            Pk(:,i+1,r+1) = (1-a(i+1,r+1)) * Pk(:,i,r) + ...
                                a(i+1,r+1) * Pk(:,i+1,r);
                        end
                    end
                    C(:,j) = Pk(:,ix-s,d-s+1);
                elseif ix == numel(t)
                    C(:,j) = P(:,end);
                else
                    C(:,j) = P(:,ix-d);
                end
            end
        end

        function ix = bspline_deboor_interval(~, u,t)                      
            i = bsxfun(@ge, u, t) & bsxfun(@lt, u, [t(2:end) 2*t(end)]);   
            [row,col] = find(i);
            [~,ind] = sort(row);
            ix = col(ind);
        end

        function N = bspline_funzioni_base_fun(~, h,t,n)
            Z= linspace( t(1), t(end), 10*n);
            N_h=zeros(size(Z,2),size(t,2));
            for i=1:size(Z,2)                                                          
                z = Z(i);
                for j=1:size(t,2)-1
                    if z>=t(j)&&z<t(j+1)
                        N_h(i,j) = 1;
                    else
                        N_h(i,j) = 0;
                    end
                end
                for k=2:h
                    for j=1:size(t,2)-k                                                
                        if N_h(i,j)==0
                            add1 = 0;
                        else
                            add1 = (((z-t(j))/(t(j+k-1)-t(j)))*N_h(i,j));
                        end
                        if N_h(i,j+1)==0
                            add2 = 0;
                        else
                            add2 =(((t(j+k)-z)/(t(j+k)-t(j+1)))* N_h(i,j+1));
                        end
                        N_h(i,j)=add1+add2;
                    end
                end
            end
            N = N_h(:,1:n);
        end
        
        function setConfigPanelNurbs(app)
            %Rendo visibile le componenti per la configurazione
            app.Panel_8.Visible = true;
            %Nascondo tutti gli altri pannelli
            app.Panel_2.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_6.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel.Visible = false;
            app.Panel_9.Visible = false;

            app.xNurbs = app.xCoords;
            app.yNurbs = app.yCoords;
            app.gNurbs = 3;
            app.EditField_12.Value = length(app.xNurbs);
            P = strings(length(app.xNurbs), 1);
            for i = 1:length(app.xNurbs)
                P(i) = strcat(' [', num2str(app.xNurbs(i)), ';', num2str(app.yNurbs(i)), ']');
            end
            stringP = join(P, newline);
            app.EditField_13.Value = stringP;
            app.EditField_14.Value = app.gNurbs;
            grado = app.gNurbs;
            t = [zeros(grado + 1, 1)' sort(rand(size(app.xCoords, 1) - grado - 1, 1))' ...
                ones(grado + 1, 1)'];
            app.tNurbs = t;
            app.EditField_15.Value = num2str(t);
            w = 10*rand(length(app.xNurbs), 1)';
            app.wNurbs = w;
            app.EditField_16.Value = num2str(w);
        end
        
        function closeAllLeftPanel(app)
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_9.Visible = false;
        end
        
        function [C] = nurbs_deboor_fun(app, n,t,P,w)
            w = transpose(w(:));
            P = bsxfun(@times, P, w);
            P = [P ; w];
            
            [Y] = app.bspline_deboor_fun(n,t,P);
            
            C = bsxfun(@rdivide, Y(1:end-1,:), Y(end,:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, xCoords, yCoords, image)
            app.xCoords = xCoords;
            app.yCoords = yCoords;
            app.image = image;
            app.PlotButton.Enable = false;

            %Nascondo all'avvio il pannello di configurazione
            app.Panel.Visible = false;
            app.Panel_2.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_6.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_8.Visible = false;
            app.Panel_9.Visible = false;

            %Imposto gli assi con l'immagine e i punti di controllo
            app.setImageAndPoints(app.xCoords, app.yCoords);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.methodSelected = app.DropDown.Value;
            switch app.methodSelected
                case 'Lineare a tratti'
                    % Interpolazione lineare a tratti
                    app.MostraimmagineCheckBox.Visible = true;
                    app.PlotButton.Enable = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    app.UIAxes.Title.String = 'Lineare a tratti';
                    app.setConfigPanelLineareATratti;
                case 'Curve di Bezier'
                    % Curve di Beizer
                    app.MostraimmagineCheckBox.Visible = true;
                    app.PlotButton.Enable = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    app.UIAxes.Title.String = 'Curva di Beizer';
                    app.setConfigPanelBezier;
                case 'Curve B-Spline'
                    % Curve B-Spline
                    app.MostraimmagineCheckBox.Visible = true;
                    app.PlotButton.Enable = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    app.UIAxes.Title.String = 'Curve B-Spline';
                    app.setConfigPanelBSpline;
                case 'NURBS'
                    %NURBS
                    app.MostraimmagineCheckBox.Visible = true;
                    app.PlotButton.Enable = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    app.UIAxes.Title.String = 'NURBS';
                    app.setConfigPanelNurbs;
                otherwise
                    %Nascondo tutti i pannelli si configurazione
                    app.PlotButton.Enable = false;
                    app.Panel.Visible = false;
                    app.Panel_2.Visible = false;
                    app.Panel_3.Visible = false;
                    app.Panel_4.Visible = false;
                    app.Panel_5.Visible = false;
                    app.Panel_6.Visible = false;
                    app.Panel_7.Visible = false;
                    app.Panel_8.Visible = false;
                    app.Panel_9.Visible = false;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    title(app.UIAxes, 'Interpolazione');
                    disp('Il valore non corrisponde a nessun caso.');
            end
        end

        % Value changed function: MostraimmagineCheckBox
        function MostraimmagineCheckBoxValueChanged(app, event)
            value = app.MostraimmagineCheckBox.Value;
            if value == 1
                if strcmp(app.methodSelected,'Curve B-Spline')
                    app.setImageAndPoints(app.xBSpline, app.yBSpline);
                else
                    if strcmp(app.methodSelected,'NURBS')
                        app.setImageAndPoints(app.xNurbs, app.yNurbs);
                    else
                        app.setImageAndPoints(app.xCoords, app.yCoords)
                    end
                    
                end
                
            else
                xmin = app.UIAxes.XLim(1);
                xmax = app.UIAxes.XLim(2);
                ymin = app.UIAxes.YLim(1);
                ymax = app.UIAxes.YLim(2);
                delete(findobj(app.UIAxes, 'Type', 'image'));
                xlim(app.UIAxes, [xmin, xmax]);
                ylim(app.UIAxes, [ymin, ymax]);
            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            switch app.methodSelected
                case 'Lineare a tratti'
                    app.closeAllLeftPanel;
                    app.MostraimmagineCheckBox.Visible = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    s = 1 : length(app.xCoords);
                    t = 1 : app.EditField_3.Value : length(app.xCoords);
                    x = app.xCoords;
                    y = app.yCoords;
                    [p1, p2] = app.interpolazioneATratti(s, t, x, y);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, p1, p2, 'Color', 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                case 'Curve di Bezier'
                    app.closeAllLeftPanel;
                    app.MostraimmagineCheckBox.Visible = true;
                    app.setImageAndPoints(app.xCoords, app.yCoords);
                    P = zeros(length(app.xCoords), 2);
                    for i = 1 : length (app.xCoords)
                        P (i,1) = app.xCoords (i);
                        P (i,2) = app.yCoords (i);
                    end
                    syms t;
                    B = bernsteinMatrix (length(app.xCoords)-1, t);
                    bezierCurve = simplify (B*P);
                    hold(app.UIAxes, 'on');
                    fplot(app.UIAxes, bezierCurve(1), bezierCurve(2), [0,1], 'Color', 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                case 'Curve B-Spline'
                    app.closeAllLeftPanel
                    app.MostraimmagineCheckBox.Visible = true;
                    app.setImageAndPoints(app.xBSpline, app.yBSpline);
                    n = app.gBSpline + 1;
                    P = [app.xBSpline, app.yBSpline]';
                    t = app.tBSpline;
                    C = app.bspline_deboor_fun(n, t, P);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, C(1,:), C(2,:), 'r');
                    hold(app.UIAxes, 'off')
                case 'NURBS'
                    app.closeAllLeftPanel;
                    app.MostraimmagineCheckBox.Visible = true;
                    app.setImageAndPoints(app.xNurbs, app.yNurbs);
                    n = app.gNurbs +1;
                    P = [app.xNurbs, app.yNurbs]';
                    t = app.tNurbs;
                    w = app.wNurbs;
                    C = app.nurbs_deboor_fun(n, t, P, w);
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, C(1,:), C(2,:), 'r');
                    hold(app.UIAxes, 'off')
                otherwise
                    % Codice da eseguire se valore non corrisponde a nessun caso precedente
                    app.closeAllLeftPanel;
                    app.MostraimmagineCheckBox.Visible = true;
                    disp('Il valore non corrisponde a nessun caso.');
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            %Quando cambiamo il valore del passo per il lineare a tratti
            %verifica se il valore inserito è compreso tra 0.01 e 0.9
            value = app.EditField_3.Value;
            if value > 0.9 || value < 0.01
                app.EditField_3.Value = 0.1;
                t = 1 : 0.1 : length(app.xCoords);
                app.EditField_4.Value = sprintf('%.2f ', t);
                msgbox('Il valore del passo delle t deve essere compreso tra 0.01 e 0.9', 'Errore inserimento del passo', 'error');
            else
                t = 1 : value : length(app.xCoords);
                app.EditField_4.Value = sprintf('%.2f ', t);
            end
        end

        % Button pushed function: VisualizzalamatricediBernsteinButton
        function VisualizzalamatricediBernsteinButtonPushed(app, event)
            app.Panel_3.Visible = true;
            app.MostraimmagineCheckBox.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_9.Visible = false;
            syms t;
            B = bernsteinMatrix(length(app.xCoords)-1, t);
            nElements = numel(B);
            data = cell(nElements, 2);
            indices = string("B" + (1:nElements));
            data(:, 1) = cellstr(indices);
            for i = 1:numel(B)
                data{i, 2} = char(B(i));
            end
            app.UITable.Data = data;
            
        end

        % Button pushed function: ChiudiXButton
        function ChiudiXButtonPushed(app, event)
            app.Panel_3.Visible = false;
            app.MostraimmagineCheckBox.Visible = true;
        end

        % Button pushed function: VisualizzaleequazionidellacurvaButton
        function VisualizzaleequazionidellacurvaButtonPushed(app, event)
            app.Panel_4.Visible = true;
            app.Panel_3.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_9.Visible = false;
            app.MostraimmagineCheckBox.Visible = false;
            x = app.xCoords;
            y = app.yCoords;
            syms t;
            B = bernsteinMatrix (length(x)-1, t);
            P = zeros(length(x), 2);
            for i = 1 : length (x)
                P (i,1) = x (i);
                P (i,2) = y (i);
            end
            bezierCurve = simplify (B*P);
            data = cell(1, 2);
            data{1 , 1} = char(bezierCurve(1));
            data{1, 2} = char(bezierCurve(2));
            app.TextArea.Value = data{1, 1};
            app.TextArea_2.Value = data{1, 2};
            
        end

        % Button pushed function: ChiudiXButton_2
        function ChiudiXButton_2Pushed(app, event)
            app.Panel_4.Visible = false;
            app.MostraimmagineCheckBox.Visible = true;
        end

        % Button pushed function: 
        % VisualizzailplotdeipolinomidiBernsteinButton
        function VisualizzailplotdeipolinomidiBernsteinButtonPushed(app, event)
            app.Panel_5.Visible = true;
            app.MostraimmagineCheckBox.Visible = false;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_7.Visible = false;
            app.Panel_9.Visible = false;
            cla(app.UIAxes2);
            n = length(app.xCoords) - 1;
            v_values = linspace(0, 1, 100);
            hold(app.UIAxes2, 'on');
            syms t;
            B = bernsteinMatrix(n, t);    
            for i = 1:(n+1)
                y = subs(B(:, i), v_values);
                plot(app.UIAxes2, v_values, y);
            end   
            hold(app.UIAxes2, 'off');
            grid(app.UIAxes2, 'on');
            legend(app.UIAxes2, cellstr(strcat('B(', num2str((0:n)'), ',', num2str(n), ')')));
        end

        % Button pushed function: ChiudiXButton_3
        function ChiudiXButton_3Pushed(app, event)
            app.Panel_5.Visible = false;
            app.MostraimmagineCheckBox.Visible = true;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            value = regexprep(value, '\s+', ' ');
            matches = regexp(value, '\[([\d.]+);([\d.]+)\]', 'tokens');
            x = zeros(1, numel(matches));
            y = zeros(1, numel(matches));
            for i = 1:numel(matches)
                x(i) = str2double(matches{i}{1});
                y(i) = str2double(matches{i}{2});
            end
            
            if length(x) == length(app.xBSpline)
                app.xBSpline = x';
                app.yBSpline = y';
                app.setImageAndPoints(app.xBSpline, app.yBSpline);
            else
                P = strings(length(app.xBSpline), 1);
                for i = 1:length(app.xBSpline)
                    P(i) = strcat(' [', num2str(app.xBSpline(i)), ';', num2str(app.yBSpline(i)), ']');
                end
                stringP = join(P, newline);
                app.EditField_8.Value = stringP;
                errMsg = sprintf('Il numero di punti di controllo devono essere %d. \nForse hai alterato il formato dei punti di controllo [x;y].', length(app.xBSpline));
                msgbox(errMsg, 'Errore modifica dei punti di controllo.', 'error');
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            if value < 0
                app.EditField_11.Value = app.gBSpline;
                msgbox('Il grado deve essere un numero positivo intero.', 'Errore modifica dei punti di controllo.', 'error');
            else
                if mod(value, 1) == 0
                    app.gBSpline = value;
                    grado = value;
                    if ( app.FissaipuntiCheckBox.Value == 1)
                        t = [zeros(grado + 1, 1)' sort(rand(size(app.xBSpline, 1) - grado - 1, 1))' ...
                            ones(grado + 1, 1)'];
                    else
                        t = sort(rand(length(app.xBSpline) + grado + 1, 1))';
                    end
                    app.tBSpline = t;
                    app.EditField_10.Value = num2str(t);
                else
                    app.EditField_11.Value = app.gBSpline;
                    msgbox('Il grado deve essere un numero positivo intero.', 'Errore modifica dei punti di controllo.', 'error');
                end
            end
            
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            cellArray = strsplit(value);
            numericVector = zeros(1, numel(cellArray));
            for i = 1:numel(cellArray)
                numericVector(i) = str2double(cellArray{i});
            end
            if isnan(numericVector(1))
                numericVector = numericVector(2:length(numericVector));
            end
            if length(numericVector) == (app.gBSpline + length(app.xBSpline)-1 + 2)
                isSorted = issorted(numericVector, 'ascend');
                if isSorted
                    app.tBSpline = numericVector;
                else
                    app.EditField_10.Value = num2str(app.tBSpline);
                    msgbox('I valori nel vettore dei nodi devono essere in ordine crescente.', 'Errore modifica dei punti di controllo.', 'error');
                end
            else
               app.EditField_10.Value = num2str(app.tBSpline);
               errMsg = sprintf('Il numero di nodi nel vettore dei nodi deve essere %d', (app.gBSpline + length(app.xBSpline)-1 + 2));
               msgbox(errMsg, 'Errore modifica del vettore dei nodi.', 'error'); 
            end
        end

        % Button pushed function: VisualizzailplotdellefunzioniButton
        function VisualizzailplotdellefunzioniButtonPushed(app, event)
            app.Panel_7.Visible = true;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_9.Visible = false;
            app.MostraimmagineCheckBox.Visible = false;
            cla(app.UIAxes3);

            grado = app.gBSpline;
            t = app.tBSpline;
            N = app.bspline_funzioni_base_fun(grado, t, length(app.xBSpline));
            hold(app.UIAxes3,"on");
            for j = 1 : length(app.xBSpline)
                plot(app.UIAxes3, linspace(1, 9, 10*length(app.xBSpline)), N(:, j));
            end
            hold(app.UIAxes3, 'off')
            legend(app.UIAxes3, cellstr(strcat('B(', num2str((0:length(app.xBSpline)-1)'), ',', num2str(length(app.xBSpline)-1), ')')));

        end

        % Button pushed function: ChiudiXButton_4
        function ChiudiXButton_4Pushed(app, event)
            app.Panel_7.Visible = false;
            app.MostraimmagineCheckBox.Visible = true;
        end

        % Value changed function: FissaipuntiCheckBox
        function FissaipuntiCheckBoxValueChanged(app, event)
            value = app.FissaipuntiCheckBox.Value;
            grado = app.gBSpline;
            if value == 1
               t = [zeros(grado + 1, 1)' sort(rand(size(app.xBSpline, 1) - grado - 1, 1))' ...
                ones(grado + 1, 1)'];
            else
               t = sort(rand(length(app.xBSpline) + grado + 1, 1))';
            end

            app.tBSpline = t;
            app.EditField_10.Value = num2str(t);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            value = regexprep(value, '\s+', ' ');
            matches = regexp(value, '\[([\d.]+);([\d.]+)\]', 'tokens');
            x = zeros(1, numel(matches));
            y = zeros(1, numel(matches));
            for i = 1:numel(matches)
                x(i) = str2double(matches{i}{1});
                y(i) = str2double(matches{i}{2});
            end
            
            if length(x) == length(app.xNurbs)
                app.xNurbs = x';
                app.yNurbs = y';
                app.setImageAndPoints(app.xNurbs, app.yNurbs);
            else
                P = strings(length(app.xNurbs), 1);
                for i = 1:length(app.xNurbs)
                    P(i) = strcat(' [', num2str(app.xNurbs(i)), ';', num2str(app.yNurbs(i)), ']');
                end
                stringP = join(P, newline);
                app.EditField_13.Value = stringP;
                errMsg = sprintf('Il numero di punti di controllo devono essere %d. \nForse hai alterato il formato dei punti di controllo [x;y].', length(app.xNurbs));
                msgbox(errMsg, 'Errore modifica dei punti di controllo.', 'error');
            end
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            if value < 0
                app.EditField_14.Value = app.gNurbs;
                msgbox('Il grado deve essere un numero positivo intero.', 'Errore modifica dei punti di controllo.', 'error');
            else
                if mod(value, 1) == 0
                    app.gNurbs = value;
                    grado = value;
                    if ( app.FissaipuntiCheckBox_2.Value == 1)
                        t = [zeros(grado + 1, 1)' sort(rand(size(app.xNurbs, 1) - grado - 1, 1))' ...
                            ones(grado + 1, 1)'];
                    else
                        t = sort(rand(length(app.xNurbs) + grado + 1, 1))';
                    end
                    app.tNurbs = t;
                    app.EditField_15.Value = num2str(t);
                else
                    app.EditField_14.Value = app.gNurbs;
                    msgbox('Il grado deve essere un numero positivo intero.', 'Errore modifica dei punti di controllo.', 'error');
                end
            end
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            cellArray = strsplit(value);
            numericVector = zeros(1, numel(cellArray));
            for i = 1:numel(cellArray)
                numericVector(i) = str2double(cellArray{i});
            end
            if isnan(numericVector(1))
                numericVector = numericVector(2:length(numericVector));
            end
            if length(numericVector) == (app.gNurbs + length(app.xNurbs)-1 + 2)
                isSorted = issorted(numericVector, 'ascend');
                if isSorted
                    app.tNurbs = numericVector;
                else
                    app.EditField_15.Value = num2str(app.tNurbs);
                    msgbox('I valori nel vettore dei nodi devono essere in ordine crescente.', 'Errore modifica dei punti di controllo.', 'error');
                end
            else
               app.EditField_15.Value = num2str(app.tNurbs);
               errMsg = sprintf('Il numero di nodi nel vettore dei nodi deve essere %d', (app.gNurbs + length(app.xNurbs)-1 + 2));
               msgbox(errMsg, 'Errore modifica del vettore dei nodi.', 'error'); 
            end
        end

        % Value changed function: FissaipuntiCheckBox_2
        function FissaipuntiCheckBox_2ValueChanged(app, event)
            value = app.FissaipuntiCheckBox_2.Value;
            grado = app.gNurbs;
            if value == 1
               t = [zeros(grado + 1, 1)' sort(rand(size(app.xNurbs, 1) - grado - 1, 1))' ...
                ones(grado + 1, 1)'];
            else
               t = sort(rand(length(app.xNurbs) + grado + 1, 1))';
            end

            app.tNurbs = t;
            app.EditField_15.Value = num2str(t);
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            value = app.EditField_16.Value;
            cellArray = strsplit(value);
            numericVector = zeros(1, numel(cellArray));
            for i = 1:numel(cellArray)
                numericVector(i) = str2double(cellArray{i});
            end
            if isnan(numericVector(1))
                numericVector = numericVector(2:length(numericVector));
            end
            if length(numericVector) == length(app.xNurbs)
               app.wNurbs = numericVector;
            else
               app.EditField_16.Value = num2str(app.wNurbs);
               errMsg = sprintf('Il numero di pesi nel vettore dei pesi deve essere uguale al numero dei punti di controllo. (%d)', length(app.xNurbs));
               msgbox(errMsg, 'Errore modifica del vettore dei pesi.', 'error'); 
            end
        end

        % Button pushed function: ChiudiXButton_5
        function ChiudiXButton_5Pushed(app, event)
            app.Panel_9.Visible = false;
            app.MostraimmagineCheckBox.Visible = true;
        end

        % Button pushed function: VisualizzailplotdellefunzioniButton_2
        function VisualizzailplotdellefunzioniButton_2Pushed(app, event)
            app.Panel_9.Visible = true;
            app.Panel_3.Visible = false;
            app.Panel_4.Visible = false;
            app.Panel_5.Visible = false;
            app.Panel_7.Visible = false;
            app.MostraimmagineCheckBox.Visible = false;
            cla(app.UIAxes4);

            grado = app.gNurbs;
            t = app.tNurbs;
            w = app.wNurbs;
            N = app.bspline_funzioni_base_fun(grado, t, length(app.xNurbs));
            R = (N.*w)./sum(N.*w);
            hold(app.UIAxes4,"on");
            for j = 1 : length(app.xNurbs)
                plot(app.UIAxes4, linspace(1, 9, 10*length(app.xNurbs)), R(:, j));
            end
            hold(app.UIAxes4, 'off')
            legend(app.UIAxes4, cellstr(strcat('B(', num2str((0:length(app.xNurbs)-1)'), ',', num2str(length(app.xNurbs)-1), ')')));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MODELLIDIINTERPOLAZIONELabel = uilabel(app.GridLayout);
            app.MODELLIDIINTERPOLAZIONELabel.Text = 'MODELLI DI INTERPOLAZIONE';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Interpolazione')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Seleziona modello...', 'Lineare a tratti', 'Curve di Bezier', 'Curve B-Spline', 'NURBS'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Seleziona modello...';
            app.MostraimmagineCheckBox = uicheckbox(app.GridLayout2);
            app.MostraimmagineCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostraimmagineCheckBoxValueChanged, true);
            app.MostraimmagineCheckBox.Text = 'Mostra immagine';
            app.MostraimmagineCheckBox.Value = true;
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.ConfigurazioneLabel = uilabel(app.GridLayout5);
            app.ConfigurazioneLabel.Text = 'Configurazione:';
            app.NumerodeipuntidicontrolloLabel = uilabel(app.GridLayout5);
            app.NumerodeipuntidicontrolloLabel.Text = 'Numero dei punti di controllo: ';
            app.VettoresdegliindiciLabel = uilabel(app.GridLayout5);
            app.VettoresdegliindiciLabel.Text = 'Vettore s degli indici:';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.EditField = uieditfield(app.GridLayout6, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.GridLayout5, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '1 2 3 4 5 6 7 8';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.EditField_3 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 0.1;
            app.InserisciilpassoperilvettoretdeiquerypointsLabel = uilabel(app.GridLayout5);
            app.InserisciilpassoperilvettoretdeiquerypointsLabel.Text = 'Inserisci il passo per il vettore t dei query points:';
            app.VettoretdeiquerypointsLabel = uilabel(app.GridLayout5);
            app.VettoretdeiquerypointsLabel.Text = 'Vettore t dei query points:';
            app.EditField_4 = uieditfield(app.GridLayout5, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = '1 2 3 4 5 6 7 8';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout5_2 = uigridlayout(app.Panel_2);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.EditField_7 = uieditfield(app.GridLayout6_2, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_5 = uieditfield(app.GridLayout5_2, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = '1 2 3 4 5 6 7 8';
            app.VisualizzalamatricediBernsteinButton = uibutton(app.GridLayout5_2, 'push');
            app.VisualizzalamatricediBernsteinButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzalamatricediBernsteinButtonPushed, true);
            app.VisualizzalamatricediBernsteinButton.Text = 'Visualizza la matrice di Bernstein';
            app.VisualizzailplotdeipolinomidiBernsteinButton = uibutton(app.GridLayout5_2, 'push');
            app.VisualizzailplotdeipolinomidiBernsteinButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzailplotdeipolinomidiBernsteinButtonPushed, true);
            app.VisualizzailplotdeipolinomidiBernsteinButton.Text = 'Visualizza il plot dei polinomi di Bernstein';
            app.VisualizzaleequazionidellacurvaButton = uibutton(app.GridLayout5_2, 'push');
            app.VisualizzaleequazionidellacurvaButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzaleequazionidellacurvaButtonPushed, true);
            app.VisualizzaleequazionidellacurvaButton.Text = 'Visualizza le equazioni della curva';
            app.ConfigurazioneLabel_2 = uilabel(app.GridLayout5_2);
            app.ConfigurazioneLabel_2.Text = 'Configurazione:';
            app.NumerodeipuntidicontrolloLabel_2 = uilabel(app.GridLayout5_2);
            app.NumerodeipuntidicontrolloLabel_2.Text = 'Numero dei punti di controllo: ';
            app.CoordinatepuntidicontrolloLabel = uilabel(app.GridLayout5_2);
            app.CoordinatepuntidicontrolloLabel.Text = 'Coordinate punti di controllo:';
            app.MatricediBernsteinLabel = uilabel(app.GridLayout5_2);
            app.MatricediBernsteinLabel.Text = 'Matrice di Bernstein:';
            app.CurvadiBezierLabel = uilabel(app.GridLayout5_2);
            app.CurvadiBezierLabel.Text = 'Curva di Bezier:';
            app.PlotdeipolinomidiBernsteinLabel = uilabel(app.GridLayout5_2);
            app.PlotdeipolinomidiBernsteinLabel.Text = 'Plot dei polinomi di Bernstein:';
            app.Panel_6 = uipanel(app.GridLayout2);
            app.GridLayout5_3 = uigridlayout(app.Panel_6);
            app.GridLayout6_3 = uigridlayout(app.GridLayout5_3);
            app.EditField_9 = uieditfield(app.GridLayout6_3, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_8 = uieditfield(app.GridLayout5_3, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = '1 2 3 4 5 6 7 8';
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_3);
            app.EditField_11 = uieditfield(app.GridLayout6_4, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_10 = uieditfield(app.GridLayout5_3, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Value = '1 2 3 4 5 6 7 8';
            app.FissaipuntiCheckBox = uicheckbox(app.GridLayout5_3);
            app.FissaipuntiCheckBox.ValueChangedFcn = createCallbackFcn(app, @FissaipuntiCheckBoxValueChanged, true);
            app.FissaipuntiCheckBox.Text = ' Fissa i punti';
            app.FissaipuntiCheckBox.Value = true;
            app.VisualizzailplotdellefunzioniButton = uibutton(app.GridLayout5_3, 'push');
            app.VisualizzailplotdellefunzioniButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzailplotdellefunzioniButtonPushed, true);
            app.VisualizzailplotdellefunzioniButton.Text = 'Visualizza il plot delle funzioni';
            app.ConfigurazioneLabel_3 = uilabel(app.GridLayout5_3);
            app.ConfigurazioneLabel_3.Text = 'Configurazione:';
            app.NumerodeipuntidicontrolloLabel_3 = uilabel(app.GridLayout5_3);
            app.NumerodeipuntidicontrolloLabel_3.Text = 'Numero dei punti di controllo: ';
            app.CoordinatepuntidicontrolloLabel_2 = uilabel(app.GridLayout5_3);
            app.CoordinatepuntidicontrolloLabel_2.Text = 'Coordinate punti di controllo:';
            app.VettoredeinoditLabel = uilabel(app.GridLayout5_3);
            app.VettoredeinoditLabel.Text = 'Vettore dei nodi t:';
            app.PlotdellefunzionidibaseLabel_2 = uilabel(app.GridLayout5_3);
            app.PlotdellefunzionidibaseLabel_2.Text = 'Plot delle funzioni di base:';
            app.GradoLabel = uilabel(app.GridLayout5_3);
            app.GradoLabel.Text = 'Grado:';
            app.Panel_8 = uipanel(app.GridLayout2);
            app.GridLayout12 = uigridlayout(app.Panel_8);
            app.ConfigurazioneLabel_4 = uilabel(app.GridLayout12);
            app.ConfigurazioneLabel_4.Text = 'Configurazione:';
            app.NumerodeipuntidicontrolloLabel_4 = uilabel(app.GridLayout12);
            app.NumerodeipuntidicontrolloLabel_4.Text = 'Numero dei punti di controllo: ';
            app.CoordinatepuntidicontrolloLabel_3 = uilabel(app.GridLayout12);
            app.CoordinatepuntidicontrolloLabel_3.Text = 'Coordinate punti di controllo:';
            app.EditField_13 = uieditfield(app.GridLayout12, 'text');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Value = '1 2 3 4 5 6 7 8';
            app.GradoLabel_2 = uilabel(app.GridLayout12);
            app.GradoLabel_2.Text = 'Grado:';
            app.VettoredeinoditLabel_2 = uilabel(app.GridLayout12);
            app.VettoredeinoditLabel_2.Text = 'Vettore dei nodi t:';
            app.EditField_15 = uieditfield(app.GridLayout12, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Value = '1 2 3 4 5 6 7 8';
            app.FissaipuntiCheckBox_2 = uicheckbox(app.GridLayout12);
            app.FissaipuntiCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FissaipuntiCheckBox_2ValueChanged, true);
            app.FissaipuntiCheckBox_2.Text = ' Fissa i punti';
            app.FissaipuntiCheckBox_2.Value = true;
            app.PlotdellefunzionidibaseLabel = uilabel(app.GridLayout12);
            app.PlotdellefunzionidibaseLabel.Text = 'Plot delle funzioni di base:';
            app.VisualizzailplotdellefunzioniButton_2 = uibutton(app.GridLayout12, 'push');
            app.VisualizzailplotdellefunzioniButton_2.ButtonPushedFcn = createCallbackFcn(app, @VisualizzailplotdellefunzioniButton_2Pushed, true);
            app.VisualizzailplotdellefunzioniButton_2.Text = 'Visualizza il plot delle funzioni';
            app.VettoredeipesiLabel = uilabel(app.GridLayout12);
            app.VettoredeipesiLabel.Text = 'Vettore dei pesi:';
            app.EditField_16 = uieditfield(app.GridLayout12, 'text');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_16.Value = '1 2 3 4 5 6 7 8';
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.EditField_12 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_12.Editable = 'off';
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.EditField_14 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.ChiudiXButton = uibutton(app.GridLayout8, 'push');
            app.ChiudiXButton.ButtonPushedFcn = createCallbackFcn(app, @ChiudiXButtonPushed, true);
            app.ChiudiXButton.Text = 'Chiudi X';
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'B(i)'; ''};
            app.UITable.RowName = {};
            app.MatricediBernsteinLabel_2 = uilabel(app.GridLayout8);
            app.MatricediBernsteinLabel_2.Text = 'Matrice di Bernstein';
            app.Panel_4 = uipanel(app.GridLayout2);
            app.GridLayout9 = uigridlayout(app.Panel_4);
            app.EquazionidellaBezierCurveLabel = uilabel(app.GridLayout9);
            app.EquazionidellaBezierCurveLabel.Text = 'Equazioni della Bezier Curve';
            app.BezierCurve_XLabel = uilabel(app.GridLayout9);
            app.BezierCurve_XLabel.Text = 'BezierCurve_X';
            app.BezierCurve_YLabel = uilabel(app.GridLayout9);
            app.BezierCurve_YLabel.Text = 'BezierCurve_Y';
            app.TextArea = uitextarea(app.GridLayout9);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.GridLayout9);
            app.TextArea_2.Editable = 'off';
            app.ChiudiXButton_2 = uibutton(app.GridLayout9, 'push');
            app.ChiudiXButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChiudiXButton_2Pushed, true);
            app.ChiudiXButton_2.Text = 'Chiudi X';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout10 = uigridlayout(app.Panel_5);
            app.UIAxes2 = uiaxes(app.GridLayout10);
            title(app.UIAxes2, 'Matrice di Bernsterin')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Polinomi di Bernstein')
            zlabel(app.UIAxes2, 'Z')
            app.ChiudiXButton_3 = uibutton(app.GridLayout10, 'push');
            app.ChiudiXButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChiudiXButton_3Pushed, true);
            app.ChiudiXButton_3.Text = 'Chiudi X';
            app.ScegliilmodellodiinterpolazioneLabel = uilabel(app.GridLayout2);
            app.ScegliilmodellodiinterpolazioneLabel.Text = 'Scegli il modello di interpolazione:';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Panel_7 = uipanel(app.GridLayout2);
            app.GridLayout11 = uigridlayout(app.Panel_7);
            app.UIAxes3 = uiaxes(app.GridLayout11);
            title(app.UIAxes3, 'Plot delle funzioni B-Spline')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ChiudiXButton_4 = uibutton(app.GridLayout11, 'push');
            app.ChiudiXButton_4.ButtonPushedFcn = createCallbackFcn(app, @ChiudiXButton_4Pushed, true);
            app.ChiudiXButton_4.Text = 'Chiudi X';
            app.Panel_9 = uipanel(app.GridLayout2);
            app.GridLayout15 = uigridlayout(app.Panel_9);
            app.UIAxes4 = uiaxes(app.GridLayout15);
            title(app.UIAxes4, 'Funzioni di base NURBS')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ChiudiXButton_5 = uibutton(app.GridLayout15, 'push');
            app.ChiudiXButton_5.ButtonPushedFcn = createCallbackFcn(app, @ChiudiXButton_5Pushed, true);
            app.ChiudiXButton_5.Text = 'Chiudi X';
            app.DescrizioneLabel = uilabel(app.GridLayout);
            app.DescrizioneLabel.Text = 'Seleziona il metodo di risoluzione per il problema di interpolazione a partire dai punti di controllo selezionati precedentemente.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end